classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        VariableEditFieldLabel  matlab.ui.control.Label
        VariableEditField       matlab.ui.control.EditField
        pdfEditFieldLabel       matlab.ui.control.Label
        pdfEditField            matlab.ui.control.EditField
        aEditFieldLabel         matlab.ui.control.Label
        aEditField              matlab.ui.control.NumericEditField
        bEditFieldLabel         matlab.ui.control.Label
        bEditField              matlab.ui.control.NumericEditField
        CalculateButton         matlab.ui.control.Button
        KEditFieldLabel         matlab.ui.control.Label
        KEditField              matlab.ui.control.NumericEditField
        EntropyEditFieldLabel   matlab.ui.control.Label
        EntropyEditField        matlab.ui.control.NumericEditField
        BoundEditFieldLabel     matlab.ui.control.Label
        BoundEditField          matlab.ui.control.NumericEditField
        ComputeDifferentialEntropyLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            try
            a = app.aEditField.Value
            b = app.bEditField.Value
            var = app.VariableEditField.Value
            s2 = vectorize(app.pdfEditField.Value)
            s1 = strcat('@(', var,')');
            s = strcat(s1,s2);
            fn = str2func(s);
            [norm, hx, bound]  = differential_entropy(fn, a, b);
            app.KEditField.Value = 1/norm;
            app.EntropyEditField.Value = hx;
            app.BoundEditField.Value = bound;
            catch
                app.KEditField.Value = 0;
                app.EntropyEditField.Value = 0;
                app.BoundEditField.Value = 0;
                f = app.UIFigure;
                uialert(f,'check b>a and if the pdf is function of the variable','Invalid Input');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.VariableEditFieldLabel = uilabel(app.UIFigure);
            app.VariableEditFieldLabel.Text = 'Variable';
            app.VariableEditField = uieditfield(app.UIFigure, 'text');
            app.pdfEditFieldLabel = uilabel(app.UIFigure);
            app.pdfEditFieldLabel.Text = 'pdf';
            app.pdfEditField = uieditfield(app.UIFigure, 'text');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.UIFigure, 'numeric');
            app.EntropyEditFieldLabel = uilabel(app.UIFigure);
            app.EntropyEditFieldLabel.Text = 'Entropy';
            app.EntropyEditField = uieditfield(app.UIFigure, 'numeric');
            app.BoundEditFieldLabel = uilabel(app.UIFigure);
            app.BoundEditFieldLabel.Text = 'Bound';
            app.BoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.ComputeDifferentialEntropyLabel = uilabel(app.UIFigure);
            app.ComputeDifferentialEntropyLabel.Text = 'Compute Differential Entropy';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end