classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Button                matlab.ui.control.Button
        fxyzEditFieldLabel    matlab.ui.control.Label
        fxyzEditField         matlab.ui.control.EditField
        HxEditFieldLabel      matlab.ui.control.Label
        HxEditField           matlab.ui.control.NumericEditField
        HYEditFieldLabel      matlab.ui.control.Label
        HYEditField           matlab.ui.control.NumericEditField
        HzEditFieldLabel      matlab.ui.control.Label
        HzEditField           matlab.ui.control.NumericEditField
        HXYZEditFieldLabel    matlab.ui.control.Label
        HXYZEditField         matlab.ui.control.NumericEditField
        HfXYZLabel            matlab.ui.control.Label
        HfXYZEditField        matlab.ui.control.NumericEditField
        Range1EditFieldLabel  matlab.ui.control.Label
        Range1EditField       matlab.ui.control.EditField
        Range2EditFieldLabel  matlab.ui.control.Label
        Range2EditField       matlab.ui.control.EditField
        Range3EditFieldLabel  matlab.ui.control.Label
        Range3EditField       matlab.ui.control.EditField
        pd1EditFieldLabel     matlab.ui.control.Label
        pd1EditField          matlab.ui.control.EditField
        pd2EditFieldLabel     matlab.ui.control.Label
        pd2EditField          matlab.ui.control.EditField
        pd3EditFieldLabel     matlab.ui.control.Label
        pd3EditField          matlab.ui.control.EditField
        CHECKSTextAreaLabel   matlab.ui.control.Label
        CHECKSTextArea        matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            try
            r1 = evalin('base', app.Range1EditField.Value);
            pd1 = evalin('base',app.pd1EditField.Value)
            r2 = evalin('base', app.Range2EditField.Value);
            pd2 = evalin('base',app.pd2EditField.Value)
            r3 = evalin('base', app.Range3EditField.Value);
            pd3 = evalin('base',app.pd3EditField.Value);
            f = vectorize(app.fxyzEditField.Value);
            f = strcat('@(x,y,z) ', f);
            if length(r1) ~= length(pd1) || length(r2) ~= length(pd2) length(r3) ~= length(pd3)
                 uialert(app.UIFigure,"Invalid Input, Problem with vector lengths","Invalid Input");
            else
            [Hx,Hy,Hz,H_xyz,Hfun,checks] = entropies(r1,r2,r3,pd1,pd2,pd3,str2func(f));
            app.HxEditField.Value = Hx;
            app.HYEditField.Value = Hy;
            app.HzEditField.Value = Hz;
            app.HXYZEditField.Value = H_xyz;
            app.HfXYZEditField.Value = Hfun;
            app.CHECKSTextArea.Value = sprintf(checks);
            end
            catch
                uialert(app.UIFigure,"f must be function of the variables x,y,z. write the vectors in the form [p1 p2 p3..]","Invalid Input");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.fxyzEditFieldLabel = uilabel(app.UIFigure);
            app.fxyzEditFieldLabel.Text = 'f(x,y,z)';
            app.fxyzEditField = uieditfield(app.UIFigure, 'text');
            app.HxEditFieldLabel = uilabel(app.UIFigure);
            app.HxEditFieldLabel.Text = 'H(x)';
            app.HxEditField = uieditfield(app.UIFigure, 'numeric');
            app.HYEditFieldLabel = uilabel(app.UIFigure);
            app.HYEditFieldLabel.Text = 'H(Y)';
            app.HYEditField = uieditfield(app.UIFigure, 'numeric');
            app.HzEditFieldLabel = uilabel(app.UIFigure);
            app.HzEditFieldLabel.Text = 'H(z)';
            app.HzEditField = uieditfield(app.UIFigure, 'numeric');
            app.HXYZEditFieldLabel = uilabel(app.UIFigure);
            app.HXYZEditFieldLabel.Text = 'H(X,Y,Z)';
            app.HXYZEditField = uieditfield(app.UIFigure, 'numeric');
            app.HfXYZLabel = uilabel(app.UIFigure);
            app.HfXYZLabel.Text = 'H(f(X,Y,Z))';
            app.HfXYZEditField = uieditfield(app.UIFigure, 'numeric');
            app.Range1EditFieldLabel = uilabel(app.UIFigure);
            app.Range1EditFieldLabel.Text = 'Range1';
            app.Range1EditField = uieditfield(app.UIFigure, 'text');
            app.Range2EditFieldLabel = uilabel(app.UIFigure);
            app.Range2EditFieldLabel.Text = 'Range2';
            app.Range2EditField = uieditfield(app.UIFigure, 'text');
            app.Range3EditFieldLabel = uilabel(app.UIFigure);
            app.Range3EditFieldLabel.Text = 'Range3';
            app.Range3EditField = uieditfield(app.UIFigure, 'text');
            app.pd1EditFieldLabel = uilabel(app.UIFigure);
            app.pd1EditFieldLabel.Text = 'pd1';
            app.pd1EditField = uieditfield(app.UIFigure, 'text');
            app.pd2EditFieldLabel = uilabel(app.UIFigure);
            app.pd2EditFieldLabel.Text = 'pd2';
            app.pd2EditField = uieditfield(app.UIFigure, 'text');
            app.pd3EditFieldLabel = uilabel(app.UIFigure);
            app.pd3EditFieldLabel.Text = 'pd3';
            app.pd3EditField = uieditfield(app.UIFigure, 'text');
            app.CHECKSTextAreaLabel = uilabel(app.UIFigure);
            app.CHECKSTextAreaLabel.Text = 'CHECKS';
            app.CHECKSTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end