classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EncodeButton                  matlab.ui.control.Button
        AlphabetEditFieldLabel        matlab.ui.control.Label
        AlphabetEditField             matlab.ui.control.EditField
        ProbabilityVectorEditFieldLabel  matlab.ui.control.Label
        ProbabilityVectorEditField    matlab.ui.control.EditField
        StringtoencodeEditFieldLabel  matlab.ui.control.Label
        StringtoencodeEditField       matlab.ui.control.EditField
        EncodedVectorEditFieldLabel   matlab.ui.control.Label
        EncodedVectorEditField        matlab.ui.control.EditField
        EditField                     matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EncodeButton
        function EncodeButtonPushed(app, event)
            try
            alphabet = app.AlphabetEditField.Value;
            probs = app.ProbabilityVectorEditField.Value;
            string = app.StringtoencodeEditField.Value;
            ok = controls(alphabet,probs,string);
            if ok==1
            [codeword,result] = ac(alphabet,probs,string);
            codeword = mat2str(codeword);
            if length(codeword)>1
                codeword = codeword(2:end-1);
            end
            app.EncodedVectorEditField.Value = codeword;
            app.EditField.Value = result;
            else
                uialert(app.UIFigure,"Errore Input","Errore Input");
            end
            catch
                uialert(app.UIFigure,"Errore Input. Write the distribution in the form [p1 p2 p3...]","Errore Input");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EncodeButton = uibutton(app.UIFigure, 'push');
            app.EncodeButton.ButtonPushedFcn = createCallbackFcn(app, @EncodeButtonPushed, true);
            app.EncodeButton.Text = 'Encode';
            app.AlphabetEditFieldLabel = uilabel(app.UIFigure);
            app.AlphabetEditFieldLabel.Text = 'Alphabet';
            app.AlphabetEditField = uieditfield(app.UIFigure, 'text');
            app.ProbabilityVectorEditFieldLabel = uilabel(app.UIFigure);
            app.ProbabilityVectorEditFieldLabel.Text = 'Probability Vector';
            app.ProbabilityVectorEditField = uieditfield(app.UIFigure, 'text');
            app.StringtoencodeEditFieldLabel = uilabel(app.UIFigure);
            app.StringtoencodeEditFieldLabel.Text = 'String to encode';
            app.StringtoencodeEditField = uieditfield(app.UIFigure, 'text');
            app.EncodedVectorEditFieldLabel = uilabel(app.UIFigure);
            app.EncodedVectorEditFieldLabel.Text = 'Encoded Vector';
            app.EncodedVectorEditField = uieditfield(app.UIFigure, 'text');
            app.EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end